classdef SmartClass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        ControlModesPanel             matlab.ui.container.Panel
        ManualConfigurationButton     matlab.ui.control.Button
        FallSpringModeButton          matlab.ui.control.Button
        WinterModeButton              matlab.ui.control.Button
        SummerModeButton              matlab.ui.control.Button
        WelcomeLabel                  matlab.ui.control.Label
        TitleOfTab                    matlab.ui.control.Label
        ManualTab                     matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        Panel_2                       matlab.ui.container.Panel
        SubmitChangesButton           matlab.ui.control.Button
        FanControlPanel               matlab.ui.container.Panel
        HIGHButton                    matlab.ui.control.Button
        MEDIUMButton                  matlab.ui.control.Button
        LOWButton                     matlab.ui.control.Button
        OFFButton                     matlab.ui.control.Button
        RGBLEDLampPanel               matlab.ui.container.Panel
        FinalColorOutputLabel         matlab.ui.control.Label
        BField                        matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        GField                        matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        BSlider                       matlab.ui.control.Slider
        GSlider                       matlab.ui.control.Slider
        BLabel                        matlab.ui.control.Label
        GLabel                        matlab.ui.control.Label
        RField                        matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        RSlider                       matlab.ui.control.Slider
        RLabel                        matlab.ui.control.Label
        NormalLEDLampPanel            matlab.ui.container.Panel
        BrightnessEditField           matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel      matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        Brightness0100Label           matlab.ui.control.Label
        ManualRoomConfigurationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        fanValue
        arduino
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fanValue = 0;
            app.arduino = serialport("COM3",9600);
            writeline(app.arduino, "CONNECTED");
        end

        % Button pushed function: ManualConfigurationButton
        function ManualConfigurationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ManualTab;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.BrightnessEditField.Value = value;
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            r = round(app.RSlider.Value);
            app.RField.Value = r;
            g = round(app.GSlider.Value);
            b = round(app.BSlider.Value);

            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            g = round(app.GSlider.Value);
            app.GField.Value = g;
            r = round(app.RSlider.Value);
            b = round(app.BSlider.Value);

            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            b = round(app.BSlider.Value);
            app.BField.Value = b;
            r = round(app.RSlider.Value);
            g = round(app.GSlider.Value);
            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;

        end

        % Value changed function: RField
        function RFieldValueChanged(app, event)
            r = app.RField.Value;

            % Keep value between 0 and 255
            r = max(0, min(255, r));
            app.RSlider.Value = r;
        
            % Update color preview
            g = app.GSlider.Value;
            b = app.BSlider.Value;
            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;
        end

        % Value changed function: GField
        function GFieldValueChanged(app, event)
            g = app.GField.Value;

            g = max(0, min(255, g));
            app.GSlider.Value = g;
        
            r = app.RSlider.Value;
            b = app.BSlider.Value;
            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;
        end

        % Value changed function: BField
        function BFieldValueChanged(app, event)
            b = app.BField.Value;

            b = max(0, min(255, b));
            app.BSlider.Value = b;
        
            r = app.RSlider.Value;
            g = app.GSlider.Value;
            app.FinalColorOutputLabel.BackgroundColor = [r g b] / 255;
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            % Read the user input
            b = app.BrightnessEditField.Value;

            % Clamp the value to 0–100
            b = max(0, min(100, b));

            % Update slider
            app.Slider.Value = b;
        end

        % Button pushed function: SummerModeButton
        function SummerModeButtonPushed(app, event)
            writeline(app.arduino, "SUMMER");
        end

        % Button pushed function: WinterModeButton
        function WinterModeButtonPushed(app, event)
            writeline(app.arduino, "WINTER");
        end

        % Button pushed function: FallSpringModeButton
        function FallSpringModeButtonPushed(app, event)
            writeline(app.arduino, "SPRING");
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            app.fanValue = 0;
        end

        % Button pushed function: LOWButton
        function LOWButtonPushed(app, event)
            app.fanValue = 160;
        end

        % Button pushed function: MEDIUMButton
        function MEDIUMButtonPushed(app, event)
            app.fanValue = 200;
        end

        % Button pushed function: HIGHButton
        function HIGHButtonPushed(app, event)
            app.fanValue = 255;
        end

        % Button pushed function: SubmitChangesButton
        function SubmitChangesButtonPushed(app, event)
        
                light = round(app.BrightnessEditField.Value);
                r     = round(app.RField.Value);
                g     = round(app.GField.Value);
                b     = round(app.BField.Value);
                fan   = app.fanValue;
            
               
                light = max(0, min(100, light));
                r     = max(0, min(255, r));
                g     = max(0, min(255, g));
                b     = max(0, min(255, b));
            
             
                cmd = sprintf("MAN %d %d %d %d %d", light, r, g, b, fan);
            
              
                writeline(app.arduino, cmd);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomeTab.Tag = 'HomeTag';
            app.TitleOfTab = uilabel(app.HomeTab);
            app.TitleOfTab.Text = 'SMART CLASSROOM INTERFACE';
            app.WelcomeLabel = uilabel(app.HomeTab);
            app.WelcomeLabel.Text = 'Welcome to Clasroom ....';
            app.ControlModesPanel = uipanel(app.HomeTab);
            app.ControlModesPanel.Title = 'Control Modes';
            app.SummerModeButton = uibutton(app.ControlModesPanel, 'push');
            app.SummerModeButton.ButtonPushedFcn = createCallbackFcn(app, @SummerModeButtonPushed, true);
            app.SummerModeButton.Text = ' ☀️ Summer Mode';
            app.WinterModeButton = uibutton(app.ControlModesPanel, 'push');
            app.WinterModeButton.ButtonPushedFcn = createCallbackFcn(app, @WinterModeButtonPushed, true);
            app.WinterModeButton.Text = '❄️ Winter Mode';
            app.FallSpringModeButton = uibutton(app.ControlModesPanel, 'push');
            app.FallSpringModeButton.ButtonPushedFcn = createCallbackFcn(app, @FallSpringModeButtonPushed, true);
            app.FallSpringModeButton.Text = '🍂 Fall/Spring Mode';
            app.ManualConfigurationButton = uibutton(app.ControlModesPanel, 'push');
            app.ManualConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualConfigurationButtonPushed, true);
            app.ManualConfigurationButton.Text = '⚙️ Manual Configuration';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ManualTab.Tag = 'ManualTab';
            app.ManualRoomConfigurationLabel = uilabel(app.ManualTab);
            app.ManualRoomConfigurationLabel.Text = 'Manual Room Configuration';
            app.Panel = uipanel(app.ManualTab);
            app.NormalLEDLampPanel = uipanel(app.Panel);
            app.NormalLEDLampPanel.Title = 'Normal LED Lamp';
            app.Brightness0100Label = uilabel(app.NormalLEDLampPanel);
            app.Brightness0100Label.Text = 'Brightness (0 - 100 %)';
            app.Slider = uislider(app.NormalLEDLampPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.BrightnessEditFieldLabel = uilabel(app.NormalLEDLampPanel);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.NormalLEDLampPanel, 'numeric');
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessEditField.Value = 50;
            app.RGBLEDLampPanel = uipanel(app.Panel);
            app.RGBLEDLampPanel.Title = 'RGB LED Lamp';
            app.RLabel = uilabel(app.RGBLEDLampPanel);
            app.RLabel.Text = 'R:';
            app.RSlider = uislider(app.RGBLEDLampPanel);
            app.RSlider.Limits = [0 255];
            app.RSlider.MajorTicks = [];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.Value = 128;
            app.Label = uilabel(app.RGBLEDLampPanel);
            app.Label.Text = '';
            app.RField = uieditfield(app.RGBLEDLampPanel, 'numeric');
            app.RField.ValueChangedFcn = createCallbackFcn(app, @RFieldValueChanged, true);
            app.RField.Value = 128;
            app.GLabel = uilabel(app.RGBLEDLampPanel);
            app.GLabel.Text = 'G:';
            app.BLabel = uilabel(app.RGBLEDLampPanel);
            app.BLabel.Text = 'B:';
            app.GSlider = uislider(app.RGBLEDLampPanel);
            app.GSlider.Limits = [0 255];
            app.GSlider.MajorTicks = [];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.Value = 128;
            app.BSlider = uislider(app.RGBLEDLampPanel);
            app.BSlider.Limits = [0 255];
            app.BSlider.MajorTicks = [];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.Value = 128;
            app.Label_2 = uilabel(app.RGBLEDLampPanel);
            app.Label_2.Text = '';
            app.GField = uieditfield(app.RGBLEDLampPanel, 'numeric');
            app.GField.ValueChangedFcn = createCallbackFcn(app, @GFieldValueChanged, true);
            app.GField.Value = 128;
            app.Label_3 = uilabel(app.RGBLEDLampPanel);
            app.Label_3.Text = '';
            app.BField = uieditfield(app.RGBLEDLampPanel, 'numeric');
            app.BField.ValueChangedFcn = createCallbackFcn(app, @BFieldValueChanged, true);
            app.BField.Value = 128;
            app.FinalColorOutputLabel = uilabel(app.RGBLEDLampPanel);
            app.FinalColorOutputLabel.Text = 'Final Color Output';
            app.FanControlPanel = uipanel(app.Panel);
            app.FanControlPanel.Title = 'Fan Control';
            app.OFFButton = uibutton(app.FanControlPanel, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.LOWButton = uibutton(app.FanControlPanel, 'push');
            app.LOWButton.ButtonPushedFcn = createCallbackFcn(app, @LOWButtonPushed, true);
            app.LOWButton.Text = 'LOW';
            app.MEDIUMButton = uibutton(app.FanControlPanel, 'push');
            app.MEDIUMButton.ButtonPushedFcn = createCallbackFcn(app, @MEDIUMButtonPushed, true);
            app.MEDIUMButton.Text = 'MEDIUM';
            app.HIGHButton = uibutton(app.FanControlPanel, 'push');
            app.HIGHButton.ButtonPushedFcn = createCallbackFcn(app, @HIGHButtonPushed, true);
            app.HIGHButton.Text = 'HIGH';
            app.Panel_2 = uipanel(app.Panel);
            app.SubmitChangesButton = uibutton(app.Panel_2, 'push');
            app.SubmitChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitChangesButtonPushed, true);
            app.SubmitChangesButton.Text = 'Submit Changes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartClass

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end